classdef TokaLab_app_v_1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Button_Initialise     matlab.ui.control.Button
        Hyperlink_GitHub      matlab.ui.control.Hyperlink
        Hyperlink_Mathwork_FileExchange  matlab.ui.control.Hyperlink
        Hyperlink_LinkedIn    matlab.ui.control.Hyperlink
        Image_TokaLab_banner  matlab.ui.control.Image
        UIAxes_1              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_4              matlab.ui.control.UIAxes
        UIAxes_5              matlab.ui.control.UIAxes
        UIAxes_6              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tok                 % tokamak class
        geo                 % geometry class
        equi                % equilibrium class
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Initialise
        function Button_InitialisePushed(app, event)
            
            % tokamak class
            app.tok = tokamak;
            app.tok = app.tok.machine_upload();
            app.tok = app.tok.scenario_upload(1,1);
            app.tok = app.tok.kinetic_upload();

            % geometry class
            app.geo = geometry;
            app.geo = app.geo.import_geometry(app.tok);
            app.geo = app.geo.build_geometry();
            app.geo = app.geo.inside_wall();

            app.equi = equilibrium();
            app.equi = app.equi.import_configuration(app.geo,app.tok.config);
            app.equi = app.equi.import_classes();

            %% Plot section
            hold(app.UIAxes_1, 'off')
            plot(app.UIAxes_1,app.equi.separatrix.R_sep_target,app.equi.separatrix.Z_sep_target,'-r','LineWidth',1.2)
            hold(app.UIAxes_1, 'on')
            plot(app.UIAxes_1,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.UIAxes_1,"equal")
            grid(app.UIAxes_1,"on")
            grid(app.UIAxes_1,"minor")
            xlabel(app.UIAxes_1,"R [m]")
            ylabel(app.UIAxes_1,"Z [m]")
            xlim(app.UIAxes_1,[min(app.geo.R) max(app.geo.R)])
            ylim(app.UIAxes_1,[min(app.geo.Z) max(app.geo.Z)])

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_6 = uiaxes(app.GridLayout);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_5 = uiaxes(app.GridLayout);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'GS - solution')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_1 = uiaxes(app.GridLayout);
            title(app.UIAxes_1, 'Geometry -  Target Separatrix')
            xlabel(app.UIAxes_1, 'R [m]')
            ylabel(app.UIAxes_1, 'Z [m]')
            zlabel(app.UIAxes_1, 'Z')
            app.Image_TokaLab_banner = uiimage(app.GridLayout);
            app.Image_TokaLab_banner.ImageSource = fullfile(pathToMLAPP, 'images', 'TokaLab_banner.png');
            app.Hyperlink_LinkedIn = uihyperlink(app.GridLayout);
            app.Hyperlink_LinkedIn.URL = 'https://www.linkedin.com/company/tokalab-fusion/';
            app.Hyperlink_LinkedIn.Text = 'LinkedIn';
            app.Hyperlink_Mathwork_FileExchange = uihyperlink(app.GridLayout);
            app.Hyperlink_Mathwork_FileExchange.URL = 'https://it.mathworks.com/matlabcentral/fileexchange/182014-tokalab';
            app.Hyperlink_Mathwork_FileExchange.Text = 'FileExhange';
            app.Hyperlink_GitHub = uihyperlink(app.GridLayout);
            app.Hyperlink_GitHub.URL = 'https://github.com/TokaLab/VirtualLab';
            app.Hyperlink_GitHub.Text = 'GitHub';
            app.Button_Initialise = uibutton(app.GridLayout, 'push');
            app.Button_Initialise.ButtonPushedFcn = createCallbackFcn(app, @Button_InitialisePushed, true);
            app.Button_Initialise.Text = 'Initialise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TokaLab_app_v_1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end