classdef TokaLab_app_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Button_Update                  matlab.ui.control.Button
        Button_Initialise              matlab.ui.control.Button
        SelecttheTokamakDropDown       matlab.ui.control.DropDown
        SelecttheTokamakDropDownLabel  matlab.ui.control.Label
        Image_TokaLab_banner           matlab.ui.control.Image
        a                              matlab.ui.control.Spinner
        MinorRadiusamSpinnerLabel      matlab.ui.control.Label
        Zmag                           matlab.ui.control.Spinner
        MagneticAxisZpositionmSpinnerLabel  matlab.ui.control.Label
        MagneticMajorRadiusRmSpinner   matlab.ui.control.Spinner
        MagneticMajorRadiusRmSpinnerLabel  matlab.ui.control.Label
        TargetSeparatrixParametersLabel  matlab.ui.control.Label
        gp2Spinner                     matlab.ui.control.Spinner
        gp2SpinnerLabel                matlab.ui.control.Label
        gp1Spinner                     matlab.ui.control.Spinner
        gp1SpinnerLabel                matlab.ui.control.Label
        gn2Spinner                     matlab.ui.control.Spinner
        gn2SpinnerLabel                matlab.ui.control.Label
        gn1Spinner                     matlab.ui.control.Spinner
        gn1SpinnerLabel                matlab.ui.control.Label
        d2Spinner                      matlab.ui.control.Spinner
        d2SpinnerLabel                 matlab.ui.control.Label
        d1Spinner                      matlab.ui.control.Spinner
        d1SpinnerLabel                 matlab.ui.control.Label
        k2Spinner                      matlab.ui.control.Spinner
        k2SpinnerLabel                 matlab.ui.control.Label
        k1Spinner                      matlab.ui.control.Spinner
        k1SpinnerLabel                 matlab.ui.control.Label
        gammabottomrightLabel          matlab.ui.control.Label
        gammabottomleftLabel           matlab.ui.control.Label
        gammauprightLabel              matlab.ui.control.Label
        gammaupleftLabel               matlab.ui.control.Label
        BottomElongationLabel          matlab.ui.control.Label
        UpperElongationLabel           matlab.ui.control.Label
        BottomTriangularityLabel       matlab.ui.control.Label
        UpperTriangularityLabel        matlab.ui.control.Label
        Hyperlink_LinkedIn             matlab.ui.control.Hyperlink
        Hyperlink_Mathwork_FileExchange  matlab.ui.control.Hyperlink
        Hyperlink_GitHub               matlab.ui.control.Hyperlink
        CenterPanel                    matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        GSsolverparametersLabel        matlab.ui.control.Label
        PlasmaandProfileParametersLabel  matlab.ui.control.Label
        Check_GSsolver                 matlab.ui.control.CheckBox
        IterationSpinner               matlab.ui.control.Spinner
        IterationSpinnerLabel          matlab.ui.control.Label
        na_2Spinner                    matlab.ui.control.Spinner
        na_2SpinnerLabel               matlab.ui.control.Label
        na_1Spinner                    matlab.ui.control.Spinner
        na_1SpinnerLabel               matlab.ui.control.Label
        n_s1e19Spinner                 matlab.ui.control.Spinner
        n_s1e19SpinnerLabel            matlab.ui.control.Label
        n_01e19Spinner                 matlab.ui.control.Spinner
        n_01e19SpinnerLabel            matlab.ui.control.Label
        J_ta_2Spinner                  matlab.ui.control.Spinner
        J_ta_2SpinnerLabel             matlab.ui.control.Label
        J_ta_1Spinner                  matlab.ui.control.Spinner
        J_ta_1SpinnerLabel             matlab.ui.control.Label
        beta_0Spinner                  matlab.ui.control.Spinner
        beta_0SpinnerLabel             matlab.ui.control.Label
        I_pMASpinner                   matlab.ui.control.Spinner
        I_pMASpinnerLabel              matlab.ui.control.Label
        B_tTSpinner                    matlab.ui.control.Spinner
        B_tTSpinnerLabel               matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        DropDown                       matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        PlotField                      matlab.ui.control.UIAxes
        PlotProfile1                   matlab.ui.control.UIAxes
        PlotProfile3                   matlab.ui.control.UIAxes
        PlotProfile2                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        tok                 % tokamak class
        geo                 % geometry class
        equi                % equilibrium class

        PickUp              % Pick Up coils
        FluxLoops           % Flux Loops
        SaddleCoils         % Saddle Loops
        TS                  % Thomson Scattering
        IntPol              % Interferometer Polarimeter

    end
    
    methods (Access = private)
        
        function function_plotfield(app)

            Field_1 = strsplit(app.DropDown.Value);
            Field_1 = Field_1{1};
            Y = app.equi.(Field_1);
            hold(app.PlotField, 'off')
            contourf(app.PlotField,app.equi.geo.grid.Rg,app.equi.geo.grid.Zg,app.equi.(Field_1),30,'LineStyle','none')
            colormap(app.PlotField,'Jet')
            colorbar(app.PlotField)
            clim(app.PlotField,[min(Y(app.geo.wall.inside),[],'all') max(Y(app.geo.wall.inside),[],'all')])
            hold(app.PlotField, 'on')
            fill(app.PlotField,app.geo.wall.solid_R,app.geo.wall.solid_Z,[200 200 200]/255,'LineStyle','none')
            contour(app.PlotField,app.equi.geo.grid.Rg,app.equi.geo.grid.Zg,app.equi.psi_n,[0 0.25 0.5 0.75 0.9 0.95 0.99 1 1.01],'w')
            plot(app.PlotField,app.equi.separatrix.R_sep_target,app.equi.separatrix.Z_sep_target,'-r','LineWidth',1.2)
            plot(app.PlotField,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.PlotField,"equal")
            grid(app.PlotField,"on")
            grid(app.PlotField,"minor")
            xlabel(app.PlotField,"R [m]")
            ylabel(app.PlotField,"Z [m]")
            xlim(app.PlotField,[min(app.geo.R) max(app.geo.R)])
            ylim(app.PlotField,[min(app.geo.Z) max(app.geo.Z)])
            title(app.PlotField,"GS solution - " + app.DropDown.Value)
            
        end
        
        function function_plotprofiles(app)

                app.equi = app.equi.evaluate_profiles_1D();
                hold(app.PlotProfile1,'off')
                plot(app.PlotProfile1,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.ne,...
                    "LineWidth",2)
                hold(app.PlotProfile1,'on')
                plot(app.PlotProfile1,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.ni,...
                    "LineWidth",2)
                xlabel(app.PlotProfile1,"\psi_n [arb.units]")
                ylabel(app.PlotProfile1,"n [m^{-3}]")
                axis(app.PlotProfile1,"normal")
                xlim(app.PlotProfile1,[0 1])
                ylim(app.PlotProfile1,[0 inf])
                grid(app.PlotProfile1,"on")
                grid(app.PlotProfile1,"minor")
                legend(app.PlotProfile1,"electron","ion")

                hold(app.PlotProfile2,'off')
                plot(app.PlotProfile2,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.Te,...
                    "LineWidth",2)
                hold(app.PlotProfile2,'on')
                plot(app.PlotProfile2,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.Ti,...
                    "LineWidth",2)
                xlabel(app.PlotProfile2,"\psi_n [arb.units]")
                ylabel(app.PlotProfile2,"T [eV]")
                axis(app.PlotProfile2,"normal")
                xlim(app.PlotProfile2,[0 1])
                ylim(app.PlotProfile2,[0 inf])
                grid(app.PlotProfile2,"on")
                grid(app.PlotProfile2,"minor")
                legend(app.PlotProfile2,"electron","ion")

                hold(app.PlotProfile3,'off')
                plot(app.PlotProfile3,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.pe,...
                    "LineWidth",2)
                hold(app.PlotProfile3,'on')
                plot(app.PlotProfile3,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.pi,...
                    "LineWidth",2)
                xlabel(app.PlotProfile3,"\psi_n [arb.units]")
                ylabel(app.PlotProfile3,"p [Pa]")
                axis(app.PlotProfile3,"normal")
                xlim(app.PlotProfile3,[0 1])
                ylim(app.PlotProfile3,[0 inf])
                grid(app.PlotProfile3,"on")
                grid(app.PlotProfile3,"minor")
                legend(app.PlotProfile3,"electron","ion")

        end
    
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Callback_StartUp(app)
            
            machine = app.SelecttheTokamakDropDown.Value;
 
            % tokamak class
            clear app.tok
            app.tok = tokamak;
            app.tok = app.tok.machine_upload(machine);
            app.tok = app.tok.scenario_upload(1,1);
            app.tok = app.tok.kinetic_upload();

            % geometry class
            clear app.geo
            app.geo = geometry;
            app.geo = app.geo.import_geometry(app.tok);
            app.geo = app.geo.build_geometry();
            app.geo = app.geo.inside_wall();

            clear app.equi
            app.equi = equilibrium();
            app.equi = app.equi.import_configuration(app.geo,app.tok.config);
            app.equi = app.equi.import_classes();

            %% Plot section
          
            hold(app.PlotField,"off")
            plot(app.PlotField,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.PlotField,"equal")
            grid(app.PlotField,"on")
            grid(app.PlotField,"minor")
            xlabel(app.PlotField,"R [m]")
            ylabel(app.PlotField,"Z [m]")
            xlim(app.PlotField,[min(app.geo.R) max(app.geo.R)])
            ylim(app.PlotField,[min(app.geo.Z) max(app.geo.Z)])
 
            %% wall solid 
            Rm = min(app.geo.R,[],'all');
            RM = max(app.geo.R,[],'all');
            Zm = min(app.geo.Z,[],'all');
            ZM = max(app.geo.Z,[],'all');
            
            app.geo.wall.solid_R = [Rm Rm RM RM RM RM Rm Rm Rm app.geo.wall.R];
            app.geo.wall.solid_Z = [Zm Zm Zm Zm ZM ZM ZM ZM Zm app.geo.wall.Z];

            %% Update values on spinner

            % Separatrix Spinner

            app.MagneticMajorRadiusRmSpinner.Value = app.equi.config.separatrix.R0;
            app.Zmag.Value = app.equi.config.separatrix.Z0;
            app.a.Value = app.equi.config.separatrix.a;
            
            app.k1Spinner.Value = app.equi.config.separatrix.k1;
            app.k2Spinner.Value = app.equi.config.separatrix.k2;
            app.d1Spinner.Value = app.equi.config.separatrix.d1;
            app.d2Spinner.Value = app.equi.config.separatrix.d2;

            app.gn1Spinner.Value = app.equi.config.separatrix.gamma_n_1*180/pi;
            app.gn2Spinner.Value = app.equi.config.separatrix.gamma_n_2*180/pi;
            app.gp1Spinner.Value = app.equi.config.separatrix.gamma_p_1*180/pi;
            app.gp2Spinner.Value = app.equi.config.separatrix.gamma_p_2*180/pi;

            % Kinetic / Profile spinner
            app.B_tTSpinner.Value = app.equi.config.toroidal_current.Bt;
            app.I_pMASpinner.Value = app.equi.config.toroidal_current.Ip/1e6;
            app.J_ta_1Spinner.Value = app.equi.config.toroidal_current.alpha_1;
            app.J_ta_2Spinner.Value = app.equi.config.toroidal_current.alpha_2;
            app.beta_0Spinner.Value = app.equi.config.toroidal_current.beta_0;

            app.n_01e19Spinner.Value = app.equi.config.kinetic.n0/1e19;
            app.n_s1e19Spinner.Value = app.equi.config.kinetic.nsep/1e19;
            app.na_1Spinner.Value = app.equi.config.kinetic.a1;
            app.na_2Spinner.Value = app.equi.config.kinetic.a2;

            %% Solve GS
            app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);
            app.equi.config.GSsolver.Plotting = 0;
            app.equi = app.equi.solve_equilibrium_dimless();
            app.equi = app.equi.equi_pp2();
            app.equi = app.equi.compute_profiles();

            %% Plot
            function_plotfield(app)
            function_plotprofiles(app)


        end

        % Callback function: Button_Initialise, SelecttheTokamakDropDown
        function Callback_Initialise(app, event)
            
            machine = app.SelecttheTokamakDropDown.Value;
 
            % tokamak class
            clear app.tok
            app.tok = tokamak;
            app.tok = app.tok.machine_upload(machine);
            app.tok = app.tok.scenario_upload(1,1);
            app.tok = app.tok.kinetic_upload();

            % geometry class
            clear app.geo
            app.geo = geometry;
            app.geo = app.geo.import_geometry(app.tok);
            app.geo = app.geo.build_geometry();
            app.geo = app.geo.inside_wall();

            clear app.equi
            app.equi = equilibrium();
            app.equi = app.equi.import_configuration(app.geo,app.tok.config);
            app.equi = app.equi.import_classes();

            %% Plot section
          
            hold(app.PlotField,"off")
            plot(app.PlotField,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.PlotField,"equal")
            grid(app.PlotField,"on")
            grid(app.PlotField,"minor")
            xlabel(app.PlotField,"R [m]")
            ylabel(app.PlotField,"Z [m]")
            xlim(app.PlotField,[min(app.geo.R) max(app.geo.R)])
            ylim(app.PlotField,[min(app.geo.Z) max(app.geo.Z)])
 
            %% wall solid 
            Rm = min(app.geo.R,[],'all');
            RM = max(app.geo.R,[],'all');
            Zm = min(app.geo.Z,[],'all');
            ZM = max(app.geo.Z,[],'all');
            
            app.geo.wall.solid_R = [Rm Rm RM RM RM RM Rm Rm Rm app.geo.wall.R];
            app.geo.wall.solid_Z = [Zm Zm Zm Zm ZM ZM ZM ZM Zm app.geo.wall.Z];

            %% Update values on spinner

            % Separatrix Spinner

            app.MagneticMajorRadiusRmSpinner.Value = app.equi.config.separatrix.R0;
            app.Zmag.Value = app.equi.config.separatrix.Z0;
            app.a.Value = app.equi.config.separatrix.a;
            
            app.k1Spinner.Value = app.equi.config.separatrix.k1;
            app.k2Spinner.Value = app.equi.config.separatrix.k2;
            app.d1Spinner.Value = app.equi.config.separatrix.d1;
            app.d2Spinner.Value = app.equi.config.separatrix.d2;

            app.gn1Spinner.Value = app.equi.config.separatrix.gamma_n_1*180/pi;
            app.gn2Spinner.Value = app.equi.config.separatrix.gamma_n_2*180/pi;
            app.gp1Spinner.Value = app.equi.config.separatrix.gamma_p_1*180/pi;
            app.gp2Spinner.Value = app.equi.config.separatrix.gamma_p_2*180/pi;

            % Kinetic / Profile spinner
            app.B_tTSpinner.Value = app.equi.config.toroidal_current.Bt;
            app.I_pMASpinner.Value = app.equi.config.toroidal_current.Ip/1e6;
            app.J_ta_1Spinner.Value = app.equi.config.toroidal_current.alpha_1;
            app.J_ta_2Spinner.Value = app.equi.config.toroidal_current.alpha_2;
            app.beta_0Spinner.Value = app.equi.config.toroidal_current.beta_0;

            app.n_01e19Spinner.Value = app.equi.config.kinetic.n0/1e19;
            app.n_s1e19Spinner.Value = app.equi.config.kinetic.nsep/1e19;
            app.na_1Spinner.Value = app.equi.config.kinetic.a1;
            app.na_2Spinner.Value = app.equi.config.kinetic.a2;

            %% Solve GS
            app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);
            app.equi.config.GSsolver.Plotting = 0;
            app.equi = app.equi.solve_equilibrium_dimless();
            app.equi = app.equi.equi_pp2();
            app.equi = app.equi.compute_profiles();

            %% Plot
            function_plotfield(app)
            function_plotprofiles(app)

        end

        % Callback function: B_tTSpinner, Button_Update, Check_GSsolver, 
        % ...and 20 other components
        function Callback_Update(app, event)

            %% Update values separatrix

            app.equi.config.separatrix.R0 = app.MagneticMajorRadiusRmSpinner.Value;
            app.equi.config.separatrix.Z0 = app.Zmag.Value;
            app.equi.config.separatrix.a = app.a.Value;

            app.equi.config.separatrix.k1 = app.k1Spinner.Value;
            app.equi.config.separatrix.k2 = app.k2Spinner.Value;
            app.equi.config.separatrix.d1 = app.d1Spinner.Value;
            app.equi.config.separatrix.d2 = app.d2Spinner.Value;

            app.equi.config.separatrix.gamma_n_1 = app.gn1Spinner.Value*pi/180;
            app.equi.config.separatrix.gamma_n_2 = app.gn2Spinner.Value*pi/180;
            app.equi.config.separatrix.gamma_p_1 = app.gp1Spinner.Value*pi/180;
            app.equi.config.separatrix.gamma_p_2 = app.gp2Spinner.Value*pi/180;

            %% Build new Separatrix

            app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);

            %% Update values MHD and Kinetic

            app.equi.config.toroidal_current.Bt = app.B_tTSpinner.Value;
            app.equi.config.toroidal_current.Ip = app.I_pMASpinner.Value*1e6;
            app.equi.config.toroidal_current.alpha_1 = app.J_ta_1Spinner.Value;
            app.equi.config.toroidal_current.alpha_2 = app.J_ta_2Spinner.Value;
            app.equi.config.toroidal_current.beta_0 = app.beta_0Spinner.Value;

            app.equi.config.kinetic.n0 = app.n_01e19Spinner.Value*1e19;
            app.equi.config.kinetic.nsep = app.n_s1e19Spinner.Value*1e19;
            app.equi.config.kinetic.a1 = app.na_1Spinner.Value;
            app.equi.config.kinetic.a2 = app.na_2Spinner.Value;

            %% Plot

            hold(app.PlotField, 'off')
            plot(app.PlotField,app.equi.separatrix.R_sep_target,app.equi.separatrix.Z_sep_target,'-r','LineWidth',1.2)
            hold(app.PlotField, 'on')
            plot(app.PlotField,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.PlotField,"equal")
            grid(app.PlotField,"on")
            grid(app.PlotField,"minor")
            xlabel(app.PlotField,"R [m]")
            ylabel(app.PlotField,"Z [m]")
            xlim(app.PlotField,[min(app.geo.R) max(app.geo.R)])
            ylim(app.PlotField,[min(app.geo.Z) max(app.geo.Z)])

            %% Update Equilibrium profiles
            if app.Check_GSsolver.Value

                app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);
                app.equi.config.GSsolver.Plotting = 0;

                app.equi.config.GSsolver.maxIter = app.IterationSpinner.Value;

                app.equi = app.equi.solve_equilibrium_dimless();
                app.equi = app.equi.equi_pp2();
                app.equi = app.equi.compute_profiles();

                %% Plot
                function_plotfield(app)
                function_plotprofiles(app)

            end

        end

        % Value changed function: DropDown
        function Callback_UpdatePlot(app, event)
            % value = app.DropDown.Value;

            %% Plot
            function_plotfield(app)
            function_plotprofiles(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {658, 658, 658};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {658, 658};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {238, '1x', 835};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Hyperlink_GitHub = uihyperlink(app.GridLayout2);
            app.Hyperlink_GitHub.URL = 'https://github.com/TokaLab/VirtualLab';
            app.Hyperlink_GitHub.Text = 'GitHub';
            app.Hyperlink_Mathwork_FileExchange = uihyperlink(app.GridLayout2);
            app.Hyperlink_Mathwork_FileExchange.URL = 'https://it.mathworks.com/matlabcentral/fileexchange/182014-tokalab';
            app.Hyperlink_Mathwork_FileExchange.Text = 'FileExhange';
            app.Hyperlink_LinkedIn = uihyperlink(app.GridLayout2);
            app.Hyperlink_LinkedIn.URL = 'https://www.linkedin.com/company/tokalab-fusion/';
            app.Hyperlink_LinkedIn.Text = 'LinkedIn';
            app.UpperTriangularityLabel = uilabel(app.GridLayout2);
            app.UpperTriangularityLabel.Text = 'Upper Triangularity';
            app.BottomTriangularityLabel = uilabel(app.GridLayout2);
            app.BottomTriangularityLabel.Text = 'Bottom Triangularity';
            app.UpperElongationLabel = uilabel(app.GridLayout2);
            app.UpperElongationLabel.Text = 'Upper Elongation';
            app.BottomElongationLabel = uilabel(app.GridLayout2);
            app.BottomElongationLabel.Text = 'Bottom Elongation';
            app.gammaupleftLabel = uilabel(app.GridLayout2);
            app.gammaupleftLabel.Text = 'gamma up left';
            app.gammauprightLabel = uilabel(app.GridLayout2);
            app.gammauprightLabel.Text = 'gamma up right';
            app.gammabottomleftLabel = uilabel(app.GridLayout2);
            app.gammabottomleftLabel.Text = 'gamma bottom left';
            app.gammabottomrightLabel = uilabel(app.GridLayout2);
            app.gammabottomrightLabel.Text = 'gamma bottom right';
            app.k1SpinnerLabel = uilabel(app.GridLayout2);
            app.k1SpinnerLabel.Visible = 'off';
            app.k1SpinnerLabel.Text = 'k1';
            app.k1Spinner = uispinner(app.GridLayout2);
            app.k1Spinner.Step = 0.1;
            app.k1Spinner.Limits = [0.5 3];
            app.k1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.k1Spinner.Value = 1.7;
            app.k2SpinnerLabel = uilabel(app.GridLayout2);
            app.k2SpinnerLabel.Visible = 'off';
            app.k2SpinnerLabel.Text = 'k2';
            app.k2Spinner = uispinner(app.GridLayout2);
            app.k2Spinner.Step = 0.1;
            app.k2Spinner.Limits = [0.5 3];
            app.k2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.k2Spinner.Value = 2;
            app.d1SpinnerLabel = uilabel(app.GridLayout2);
            app.d1SpinnerLabel.Visible = 'off';
            app.d1SpinnerLabel.Text = 'd1';
            app.d1Spinner = uispinner(app.GridLayout2);
            app.d1Spinner.Step = 0.1;
            app.d1Spinner.Limits = [0 5];
            app.d1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.d1Spinner.Value = 0.5;
            app.d2SpinnerLabel = uilabel(app.GridLayout2);
            app.d2SpinnerLabel.Visible = 'off';
            app.d2SpinnerLabel.Text = 'd2';
            app.d2Spinner = uispinner(app.GridLayout2);
            app.d2Spinner.Step = 0.1;
            app.d2Spinner.Limits = [0 5];
            app.d2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.d2Spinner.Value = 0.5;
            app.gn1SpinnerLabel = uilabel(app.GridLayout2);
            app.gn1SpinnerLabel.Visible = 'off';
            app.gn1SpinnerLabel.Text = 'gn1';
            app.gn1Spinner = uispinner(app.GridLayout2);
            app.gn1Spinner.Limits = [0 90];
            app.gn1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.gn2SpinnerLabel = uilabel(app.GridLayout2);
            app.gn2SpinnerLabel.Visible = 'off';
            app.gn2SpinnerLabel.Text = 'gn2';
            app.gn2Spinner = uispinner(app.GridLayout2);
            app.gn2Spinner.Step = 0.1;
            app.gn2Spinner.Limits = [0 90];
            app.gn2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.gn2Spinner.Value = 60;
            app.gp1SpinnerLabel = uilabel(app.GridLayout2);
            app.gp1SpinnerLabel.Visible = 'off';
            app.gp1SpinnerLabel.Text = 'gp1';
            app.gp1Spinner = uispinner(app.GridLayout2);
            app.gp1Spinner.Step = 0.1;
            app.gp1Spinner.Limits = [0 90];
            app.gp1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.gp2SpinnerLabel = uilabel(app.GridLayout2);
            app.gp2SpinnerLabel.Visible = 'off';
            app.gp2SpinnerLabel.Text = 'gp2';
            app.gp2Spinner = uispinner(app.GridLayout2);
            app.gp2Spinner.Step = 0.1;
            app.gp2Spinner.Limits = [0 90];
            app.gp2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.gp2Spinner.Value = 30;
            app.TargetSeparatrixParametersLabel = uilabel(app.GridLayout2);
            app.TargetSeparatrixParametersLabel.Text = 'Target Separatrix - Parameters';
            app.MagneticMajorRadiusRmSpinnerLabel = uilabel(app.GridLayout2);
            app.MagneticMajorRadiusRmSpinnerLabel.Text = 'Magnetic Major Radius R [m]';
            app.MagneticMajorRadiusRmSpinner = uispinner(app.GridLayout2);
            app.MagneticMajorRadiusRmSpinner.Step = 0.1;
            app.MagneticMajorRadiusRmSpinner.Limits = [0 Inf];
            app.MagneticMajorRadiusRmSpinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.MagneticMajorRadiusRmSpinner.Value = 6;
            app.MagneticAxisZpositionmSpinnerLabel = uilabel(app.GridLayout2);
            app.MagneticAxisZpositionmSpinnerLabel.Text = 'Magnetic Axis - Z position [m]';
            app.Zmag = uispinner(app.GridLayout2);
            app.Zmag.Step = 0.1;
            app.Zmag.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.MinorRadiusamSpinnerLabel = uilabel(app.GridLayout2);
            app.MinorRadiusamSpinnerLabel.Text = 'Minor Radius a [m]';
            app.a = uispinner(app.GridLayout2);
            app.a.Step = 0.1;
            app.a.Limits = [0 Inf];
            app.a.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.a.Value = 2;
            app.Image_TokaLab_banner = uiimage(app.GridLayout2);
            app.Image_TokaLab_banner.ImageSource = fullfile(pathToMLAPP, 'images', 'TokaLab_banner.png');
            app.SelecttheTokamakDropDownLabel = uilabel(app.GridLayout2);
            app.SelecttheTokamakDropDownLabel.Text = 'Select the Tokamak';
            app.SelecttheTokamakDropDown = uidropdown(app.GridLayout2);
            app.SelecttheTokamakDropDown.Items = {'Tokalab', 'DTT-like', 'JET-like'};
            app.SelecttheTokamakDropDown.ValueChangedFcn = createCallbackFcn(app, @Callback_Initialise, true);
            app.SelecttheTokamakDropDown.Value = 'Tokalab';
            app.Button_Initialise = uibutton(app.GridLayout2, 'push');
            app.Button_Initialise.ButtonPushedFcn = createCallbackFcn(app, @Callback_Initialise, true);
            app.Button_Initialise.Text = 'Initialise';
            app.Button_Update = uibutton(app.GridLayout2, 'push');
            app.Button_Update.ButtonPushedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.Button_Update.Text = 'Update';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.B_tTSpinnerLabel = uilabel(app.GridLayout3);
            app.B_tTSpinnerLabel.Text = 'B_t [T]';
            app.B_tTSpinner = uispinner(app.GridLayout3);
            app.B_tTSpinner.Step = 0.1;
            app.B_tTSpinner.Limits = [0 Inf];
            app.B_tTSpinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.B_tTSpinner.Value = 6;
            app.I_pMASpinnerLabel = uilabel(app.GridLayout3);
            app.I_pMASpinnerLabel.Text = 'I_p [MA]';
            app.I_pMASpinner = uispinner(app.GridLayout3);
            app.I_pMASpinner.Step = 0.1;
            app.I_pMASpinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.I_pMASpinner.Value = -12;
            app.beta_0SpinnerLabel = uilabel(app.GridLayout3);
            app.beta_0SpinnerLabel.Text = 'beta_0';
            app.beta_0Spinner = uispinner(app.GridLayout3);
            app.beta_0Spinner.Step = 0.1;
            app.beta_0Spinner.Limits = [0 Inf];
            app.beta_0Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.beta_0Spinner.Value = 0.5;
            app.J_ta_1SpinnerLabel = uilabel(app.GridLayout3);
            app.J_ta_1SpinnerLabel.Text = 'J_t a_1';
            app.J_ta_1Spinner = uispinner(app.GridLayout3);
            app.J_ta_1Spinner.Step = 0.1;
            app.J_ta_1Spinner.Limits = [0 Inf];
            app.J_ta_1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.J_ta_1Spinner.Value = 2;
            app.J_ta_2SpinnerLabel = uilabel(app.GridLayout3);
            app.J_ta_2SpinnerLabel.Text = 'J_t a_2';
            app.J_ta_2Spinner = uispinner(app.GridLayout3);
            app.J_ta_2Spinner.Step = 0.1;
            app.J_ta_2Spinner.Limits = [0 Inf];
            app.J_ta_2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.J_ta_2Spinner.Value = 2;
            app.n_01e19SpinnerLabel = uilabel(app.GridLayout3);
            app.n_01e19SpinnerLabel.Text = 'n_0 [1e19]';
            app.n_01e19Spinner = uispinner(app.GridLayout3);
            app.n_01e19Spinner.Step = 0.1;
            app.n_01e19Spinner.Limits = [0 Inf];
            app.n_01e19Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.n_01e19Spinner.Value = 10;
            app.n_s1e19SpinnerLabel = uilabel(app.GridLayout3);
            app.n_s1e19SpinnerLabel.Text = 'n_s [1e19]';
            app.n_s1e19Spinner = uispinner(app.GridLayout3);
            app.n_s1e19Spinner.Step = 0.001;
            app.n_s1e19Spinner.Limits = [0 Inf];
            app.n_s1e19Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.n_s1e19Spinner.Value = 0.01;
            app.na_1SpinnerLabel = uilabel(app.GridLayout3);
            app.na_1SpinnerLabel.Text = 'n a_1';
            app.na_1Spinner = uispinner(app.GridLayout3);
            app.na_1Spinner.Step = 0.1;
            app.na_1Spinner.Limits = [0 Inf];
            app.na_1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.na_1Spinner.Value = 2;
            app.na_2SpinnerLabel = uilabel(app.GridLayout3);
            app.na_2SpinnerLabel.Text = 'n a_2';
            app.na_2Spinner = uispinner(app.GridLayout3);
            app.na_2Spinner.Step = 0.1;
            app.na_2Spinner.Limits = [0 Inf];
            app.na_2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.na_2Spinner.Value = 3;
            app.IterationSpinnerLabel = uilabel(app.GridLayout3);
            app.IterationSpinnerLabel.Text = 'Iteration';
            app.IterationSpinner = uispinner(app.GridLayout3);
            app.IterationSpinner.Limits = [1 100];
            app.IterationSpinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.IterationSpinner.Value = 1;
            app.Check_GSsolver = uicheckbox(app.GridLayout3);
            app.Check_GSsolver.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.Check_GSsolver.Text = 'GS solver';
            app.Check_GSsolver.Value = true;
            app.PlasmaandProfileParametersLabel = uilabel(app.GridLayout3);
            app.PlasmaandProfileParametersLabel.Text = 'Plasma and Profile Parameters';
            app.GSsolverparametersLabel = uilabel(app.GridLayout3);
            app.GSsolverparametersLabel.Text = 'GS solver parameters';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.PlotProfile2 = uiaxes(app.GridLayout4);
            title(app.PlotProfile2, 'Temperature Profiles')
            xlabel(app.PlotProfile2, '\psi_n [arb.units]')
            ylabel(app.PlotProfile2, 'T [eV]')
            zlabel(app.PlotProfile2, 'Z')
            app.PlotProfile3 = uiaxes(app.GridLayout4);
            title(app.PlotProfile3, 'Pressure Profiles')
            xlabel(app.PlotProfile3, '\psi_n [arb.units]')
            ylabel(app.PlotProfile3, 'p [Pa]')
            zlabel(app.PlotProfile3, 'Z')
            app.PlotProfile1 = uiaxes(app.GridLayout4);
            title(app.PlotProfile1, 'Density Profiles')
            xlabel(app.PlotProfile1, '\psi_n [arb. units]')
            ylabel(app.PlotProfile1, 'n [m^{-3}]')
            zlabel(app.PlotProfile1, 'Z')
            app.PlotField = uiaxes(app.GridLayout4);
            title(app.PlotField, 'GS Solution - ')
            xlabel(app.PlotField, 'R [m]')
            ylabel(app.PlotField, 'Z [m]')
            zlabel(app.PlotField, 'Z')
            app.DropDownLabel = uilabel(app.GridLayout4);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout4);
            app.DropDown.Items = {'psi [Wb/rad]', 'psi_n [arb. units]', 'Psi [Wb]', 'Br [T]', 'Bt [T]', 'Bz [T]', 'Jr [A/m^2]', 'Jt [A/m^2]', 'Jz [A/m^2]', 'pe [Pa]', 'pi [Pa]', 'ne [m^{-3}]', 'ni [m^{-3}]', 'Te [eV]', 'Ti [eV]'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlot, true);
            app.DropDown.Value = 'pe [Pa]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TokaLab_app_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Callback_StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end