classdef TokaLab_app_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Image_TokaLab_banner           matlab.ui.control.Image
        Check_ShowProfile              matlab.ui.control.CheckBox
        n_s1e19Spinner                 matlab.ui.control.Spinner
        n_s1e19SpinnerLabel            matlab.ui.control.Label
        na_2Spinner                    matlab.ui.control.Spinner
        na_2SpinnerLabel               matlab.ui.control.Label
        na_1Spinner                    matlab.ui.control.Spinner
        na_1SpinnerLabel               matlab.ui.control.Label
        n_01e19Spinner                 matlab.ui.control.Spinner
        n_01e19SpinnerLabel            matlab.ui.control.Label
        beta_0Spinner                  matlab.ui.control.Spinner
        beta_0SpinnerLabel             matlab.ui.control.Label
        J_ta_2Spinner                  matlab.ui.control.Spinner
        J_ta_2SpinnerLabel             matlab.ui.control.Label
        J_ta_1Spinner                  matlab.ui.control.Spinner
        J_ta_1SpinnerLabel             matlab.ui.control.Label
        I_pMASpinner                   matlab.ui.control.Spinner
        I_pMASpinnerLabel              matlab.ui.control.Label
        B_tTSpinner                    matlab.ui.control.Spinner
        B_tTSpinnerLabel               matlab.ui.control.Label
        IterationSpinner               matlab.ui.control.Spinner
        IterationSpinnerLabel          matlab.ui.control.Label
        Check_GSsolver                 matlab.ui.control.CheckBox
        amagSpinner                    matlab.ui.control.Spinner
        amagSpinnerLabel               matlab.ui.control.Label
        ZmagSpinner                    matlab.ui.control.Spinner
        ZmagSpinnerLabel               matlab.ui.control.Label
        RmagSpinner                    matlab.ui.control.Spinner
        RmagSpinnerLabel               matlab.ui.control.Label
        Button_Update                  matlab.ui.control.Button
        ProfileHyperparametersLabel    matlab.ui.control.Label
        Button_Initialise              matlab.ui.control.Button
        TargetSeparatrixShapeLabel     matlab.ui.control.Label
        gp2Spinner                     matlab.ui.control.Spinner
        gp2SpinnerLabel                matlab.ui.control.Label
        gp1Spinner                     matlab.ui.control.Spinner
        gp1SpinnerLabel                matlab.ui.control.Label
        gn2Spinner                     matlab.ui.control.Spinner
        gn2SpinnerLabel                matlab.ui.control.Label
        gn1Spinner                     matlab.ui.control.Spinner
        gn1SpinnerLabel                matlab.ui.control.Label
        d2Spinner                      matlab.ui.control.Spinner
        d2SpinnerLabel                 matlab.ui.control.Label
        d1Spinner                      matlab.ui.control.Spinner
        d1SpinnerLabel                 matlab.ui.control.Label
        k2Spinner                      matlab.ui.control.Spinner
        k2SpinnerLabel                 matlab.ui.control.Label
        k1Spinner                      matlab.ui.control.Spinner
        k1SpinnerLabel                 matlab.ui.control.Label
        gammabottomrightLabel          matlab.ui.control.Label
        gammabottomleftLabel           matlab.ui.control.Label
        gammauprightLabel              matlab.ui.control.Label
        gammaupleftLabel               matlab.ui.control.Label
        BottomElongationLabel          matlab.ui.control.Label
        UpperElongationLabel           matlab.ui.control.Label
        BottomTriangularityLabel       matlab.ui.control.Label
        UpperTriangularityLabel        matlab.ui.control.Label
        Hyperlink_LinkedIn             matlab.ui.control.Hyperlink
        Hyperlink_Mathwork_FileExchange  matlab.ui.control.Hyperlink
        Hyperlink_GitHub               matlab.ui.control.Hyperlink
        CenterPanel                    matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Select_2                       matlab.ui.control.DropDown
        SelectFieldDropDown_2Label     matlab.ui.control.Label
        Select_1                       matlab.ui.control.DropDown
        SelectFieldDropDownLabel       matlab.ui.control.Label
        UIAxes_1                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Button_Diagnostic_Measure      matlab.ui.control.Button
        Check_InterferometerPolarimeter  matlab.ui.control.CheckBox
        Check_ThomsonScattering        matlab.ui.control.CheckBox
        Check_FluxLoops                matlab.ui.control.CheckBox
        Check_PickUp                   matlab.ui.control.CheckBox
        Check_SaddleLoops              matlab.ui.control.CheckBox
        Button_Diagnostics             matlab.ui.control.Button
        Check_UpdateMeasurement        matlab.ui.control.CheckBox
        DiagnosticsModuleSynDiagLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        MagneticsTab                   matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        PickUpCoilsNoiseSpinner        matlab.ui.control.Spinner
        PickUpCoilsNoiseLabel          matlab.ui.control.Label
        FluxLoopNoiseSpinner           matlab.ui.control.Spinner
        FluxLoopNoiseLabel             matlab.ui.control.Label
        SaddleLoopNoiseSpinner         matlab.ui.control.Spinner
        SaddleLoopNoiseLabel           matlab.ui.control.Label
        PickUpNoiseSpinner             matlab.ui.control.Spinner
        UI_FL                          matlab.ui.control.UIAxes
        UI_SL                          matlab.ui.control.UIAxes
        UI_PU                          matlab.ui.control.UIAxes
        ThomsonScatteringTab           matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        DensityNoiseSpinner            matlab.ui.control.Spinner
        DensityNoiseLabel              matlab.ui.control.Label
        TemperatureNoiseSpinner        matlab.ui.control.Spinner
        TemperatureNoiseLabel          matlab.ui.control.Label
        UI_TS_ne                       matlab.ui.control.UIAxes
        UI_TS_Te                       matlab.ui.control.UIAxes
        InterferometerPolarimeterTab   matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        CottonMoutonNoiseSpinner       matlab.ui.control.Spinner
        CottonMoutonNoiseLabel         matlab.ui.control.Label
        FaradayRotationNoiseSpinner    matlab.ui.control.Spinner
        FaradayRotationNoiseLabel      matlab.ui.control.Label
        InterferometerNoiseSpinner     matlab.ui.control.Spinner
        InterferometerNoiseLabel       matlab.ui.control.Label
        UI_CM                          matlab.ui.control.UIAxes
        UI_Faraday                     matlab.ui.control.UIAxes
        UI_Interferometer              matlab.ui.control.UIAxes
        UIAxes_6                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        tok                 % tokamak class
        geo                 % geometry class
        equi                % equilibrium class

        PickUp              % Pick Up coils
        FluxLoops           % Flux Loops
        SaddleCoils         % Saddle Loops
        TS                  % Thomson Scattering
        IntPol              % Interferometer Polarimeter

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Initialise
        function Button_InitialisePushed(app, event)

            % tokamak class
            app.tok = tokamak;
            app.tok = app.tok.machine_upload();
            app.tok = app.tok.scenario_upload(1,1);
            app.tok = app.tok.kinetic_upload();

            % geometry class
            app.geo = geometry;
            app.geo = app.geo.import_geometry(app.tok);
            app.geo = app.geo.build_geometry();
            app.geo = app.geo.inside_wall();

            app.equi = equilibrium();
            app.equi = app.equi.import_configuration(app.geo,app.tok.config);
            app.equi = app.equi.import_classes();

            %% Plot section
            hold(app.UIAxes_1, 'off')
            plot(app.UIAxes_1,app.equi.separatrix.R_sep_target,app.equi.separatrix.Z_sep_target,'-r','LineWidth',1.2)
            hold(app.UIAxes_1, 'on')
            plot(app.UIAxes_1,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.UIAxes_1,"equal")
            grid(app.UIAxes_1,"on")
            grid(app.UIAxes_1,"minor")
            xlabel(app.UIAxes_1,"R [m]")
            ylabel(app.UIAxes_1,"Z [m]")
            xlim(app.UIAxes_1,[min(app.geo.R) max(app.geo.R)])
            ylim(app.UIAxes_1,[min(app.geo.Z) max(app.geo.Z)])

            %% SynDiag Initialisation

            app.PickUp = Diag_PickUpCoils();
            app.PickUp = app.PickUp.Upload(1);

            app.FluxLoops = Diag_FluxLoops();
            app.FluxLoops = app.FluxLoops.Upload(1);

            app.SaddleCoils = Diag_SaddleCoils();
            app.SaddleCoils = app.SaddleCoils.Upload(1);

            app.TS = Diag_ThomsonScattering();
            app.TS = app.TS.Upload(1);

            app.IntPol = Diag_InterferometerPolarimeter();
            app.IntPol = app.IntPol.Upload(1);

            %% Diagnostic geometry
            hold(app.UIAxes_6, 'off')
            plot(app.UIAxes_6,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            hold(app.UIAxes_6, 'on')
            plot(app.UIAxes_6,app.PickUp.R,app.PickUp.Z,'.','MarkerSize',16,"Color","#0072BD")
            plot(app.UIAxes_6,app.FluxLoops.R,app.FluxLoops.Z,'.','MarkerSize',16,"Color","#D95319")
            plot(app.UIAxes_6,[app.SaddleCoils.R1; app.SaddleCoils.R2],...
                [app.SaddleCoils.Z1; app.SaddleCoils.Z2],'.-','MarkerSize',16,"Color","#7E2F8E")
            plot(app.UIAxes_6,app.TS.R,app.TS.Z,'.','MarkerSize',16,"Color","#77AC30")
            plot(app.UIAxes_6, ...
                [app.IntPol.R_in';app.IntPol.R_out'],...
                [app.IntPol.Z_in';app.IntPol.Z_out'],...
                '.-','MarkerSize',16,"Color","#A2142F")
            axis(app.UIAxes_6,"equal")
            grid(app.UIAxes_6,"on")
            grid(app.UIAxes_6,"minor")
            xlabel(app.UIAxes_6,"R [m]")
            ylabel(app.UIAxes_6,"Z [m]")
            xlim(app.UIAxes_6,[min(app.geo.R) max(app.geo.R)])
            ylim(app.UIAxes_6,[min(app.geo.Z) max(app.geo.Z)])

            %% wall solid 
            Rm = min(app.geo.R,[],'all');
            RM = max(app.geo.R,[],'all');
            Zm = min(app.geo.Z,[],'all');
            ZM = max(app.geo.Z,[],'all');
            
            app.geo.wall.solid_R = [Rm Rm RM RM RM RM Rm Rm Rm app.geo.wall.R];
            app.geo.wall.solid_Z = [Zm Zm Zm Zm ZM ZM ZM ZM Zm app.geo.wall.Z];

        end

        % Callback function: B_tTSpinner, Button_Update, Check_GSsolver, 
        % ...and 22 other components
        function Button_UpdatePushed(app, event)

            %% Update values separatrix

            app.equi.config.separatrix.R0 = app.RmagSpinner.Value;
            app.equi.config.separatrix.Z0 = app.ZmagSpinner.Value;
            app.equi.config.separatrix.a = app.amagSpinner.Value;

            app.equi.config.separatrix.k1 = app.k1Spinner.Value;
            app.equi.config.separatrix.k2 = app.k2Spinner.Value;
            app.equi.config.separatrix.d1 = app.d1Spinner.Value;
            app.equi.config.separatrix.d2 = app.d2Spinner.Value;

            app.equi.config.separatrix.gamma_n_1 = app.gn1Spinner.Value*pi/180;
            app.equi.config.separatrix.gamma_n_2 = app.gn2Spinner.Value*pi/180;
            app.equi.config.separatrix.gamma_p_1 = app.gp1Spinner.Value*pi/180;
            app.equi.config.separatrix.gamma_p_2 = app.gp2Spinner.Value*pi/180;

            %% Build new Separatrix
            app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);

            %% Update values MHD and Kinetic

            app.equi.config.toroidal_current.Bt = app.B_tTSpinner.Value;
            app.equi.config.toroidal_current.Ip = app.I_pMASpinner.Value*1e6;
            app.equi.config.toroidal_current.alpha_1 = app.J_ta_1Spinner.Value;
            app.equi.config.toroidal_current.alpha_2 = app.J_ta_2Spinner.Value;
            app.equi.config.toroidal_current.beta_0 = app.beta_0Spinner.Value;

            app.equi.config.kinetic.n0 = app.n_01e19Spinner.Value*1e19;
            app.equi.config.kinetic.nsep = app.n_s1e19Spinner.Value*1e19;
            app.equi.config.kinetic.a1 = app.na_1Spinner.Value;
            app.equi.config.kinetic.a2 = app.na_2Spinner.Value;

            %% Plot

            hold(app.UIAxes_1, 'off')
            plot(app.UIAxes_1,app.equi.separatrix.R_sep_target,app.equi.separatrix.Z_sep_target,'-r','LineWidth',1.2)
            hold(app.UIAxes_1, 'on')
            plot(app.UIAxes_1,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.UIAxes_1,"equal")
            grid(app.UIAxes_1,"on")
            grid(app.UIAxes_1,"minor")
            xlabel(app.UIAxes_1,"R [m]")
            ylabel(app.UIAxes_1,"Z [m]")
            xlim(app.UIAxes_1,[min(app.geo.R) max(app.geo.R)])
            ylim(app.UIAxes_1,[min(app.geo.Z) max(app.geo.Z)])

            %% Update Equilibrium profiles
            if app.Check_GSsolver.Value

                app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);
                app.equi.config.GSsolver.Plotting = 0;

                app.equi.config.GSsolver.maxIter = app.IterationSpinner.Value;

                app.equi = app.equi.solve_equilibrium_dimless();
                app.equi = app.equi.equi_pp();
                app.equi = app.equi.compute_profiles();

                Field_1 = app.Select_1.Value;
                Y = app.equi.(Field_1);
                hold(app.UIAxes_2, 'off')
                contourf(app.UIAxes_2,app.equi.geo.grid.Rg,app.equi.geo.grid.Zg,app.equi.(Field_1),30,'LineStyle','none')
                colormap(app.UIAxes_2,'Jet')
                colorbar(app.UIAxes_2)
                clim(app.UIAxes_2,[min(Y(app.geo.wall.inside),[],'all') max(Y(app.geo.wall.inside),[],'all')])
                hold(app.UIAxes_2, 'on')
                fill(app.UIAxes_2,app.geo.wall.solid_R,app.geo.wall.solid_Z,[200 200 200]/255,'LineStyle','none')
                contour(app.UIAxes_2,app.equi.geo.grid.Rg,app.equi.geo.grid.Zg,app.equi.psi_n,[0 0.25 0.5 0.75 0.9 0.95 0.99 1 1.01],'w')
                plot(app.UIAxes_2,app.equi.separatrix.R_sep_target,app.equi.separatrix.Z_sep_target,'-r','LineWidth',1.2)
                plot(app.UIAxes_2,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
                axis(app.UIAxes_2,"equal")
                grid(app.UIAxes_2,"on")
                grid(app.UIAxes_2,"minor")
                xlabel(app.UIAxes_2,"R [m]")
                ylabel(app.UIAxes_2,"Z [m]")
                xlim(app.UIAxes_2,[min(app.geo.R) max(app.geo.R)])
                ylim(app.UIAxes_2,[min(app.geo.Z) max(app.geo.Z)])
                title(app.UIAxes_2,"GS solution - J_t [A/m^2]")

                Field_2 = app.Select_2.Value;
                Y = app.equi.(Field_2);
                hold(app.UIAxes_5, 'off')
                contourf(app.UIAxes_5,app.equi.geo.grid.Rg,app.equi.geo.grid.Zg,app.equi.(Field_2),30,'LineStyle','none')
                colormap(app.UIAxes_5,'Jet')
                colorbar(app.UIAxes_5)
                clim(app.UIAxes_5,[min(Y(app.geo.wall.inside),[],'all') max(Y(app.geo.wall.inside),[],'all')])
                hold(app.UIAxes_5, 'on')
                fill(app.UIAxes_5,app.geo.wall.solid_R,app.geo.wall.solid_Z,[200 200 200]/255,'LineStyle','none')
                contour(app.UIAxes_5,app.equi.geo.grid.Rg,app.equi.geo.grid.Zg,app.equi.psi_n,[0 0.25 0.5 0.75 0.9 0.95 0.99 1 1.01],'w')
                plot(app.UIAxes_5,app.equi.separatrix.R_sep_target,app.equi.separatrix.Z_sep_target,'-r','LineWidth',1.2)
                plot(app.UIAxes_5,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
                axis(app.UIAxes_5,"equal")
                grid(app.UIAxes_5,"on")
                grid(app.UIAxes_5,"minor")
                xlabel(app.UIAxes_5,"R [m]")
                ylabel(app.UIAxes_5,"Z [m]")
                xlim(app.UIAxes_5,[min(app.geo.R) max(app.geo.R)])
                ylim(app.UIAxes_5,[min(app.geo.Z) max(app.geo.Z)])
                title(app.UIAxes_5,"GS solution - n_e [m^{-3}]")

            end

            %% Update kinetic profiles
            if app.Check_ShowProfile.Value && app.Check_GSsolver.Value

                app.equi = app.equi.evaluate_profiles_1D();

                plot(app.UIAxes_3,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.ne,...
                    "LineWidth",2)
                xlabel(app.UIAxes_3,"\psi_n [arb.units]")
                ylabel(app.UIAxes_3,"n_e [m^{-3}]")
                axis(app.UIAxes_3,"normal")
                xlim(app.UIAxes_3,[0 1])
                ylim(app.UIAxes_3,[0 inf])
                grid(app.UIAxes_3,"on")
                grid(app.UIAxes_3,"minor")

                plot(app.UIAxes_4,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.Te,...
                    "LineWidth",2)
                xlabel(app.UIAxes_4,"\psi_n [arb.units]")
                ylabel(app.UIAxes_4,"T_e [eV]")
                axis(app.UIAxes_4,"normal")
                xlim(app.UIAxes_4,[0 1])
                ylim(app.UIAxes_4,[0 inf])
                grid(app.UIAxes_4,"on")
                grid(app.UIAxes_4,"minor")

            end

            %% Show Measurements

            if app.Check_UpdateMeasurement.Value

                app.PickUp.config.noise_random_proportional_intensity = app.PickUpCoilsNoiseSpinner.Value;
                app.SaddleCoils.config.noise_random_proportional_intensity = app.SaddleLoopNoiseSpinner.Value;
                app.FluxLoops.config.noise_random_proportional_intensity = app.FluxLoopNoiseSpinner.Value;

                app.TS.config.Te_noise_random_proportional_intensity = app.TemperatureNoiseSpinner.Value;
                app.TS.config.ne_noise_random_proportional_intensity = app.DensityNoiseSpinner.Value;

                app.IntPol.config.LID_noise_random_proportional_intensity = app.InterferometerNoiseSpinner.Value;
                app.IntPol.config.FAR_noise_random_proportional_intensity = app.FaradayRotationNoiseSpinner.Value;
                app.IntPol.config.CM_noise_random_proportional_intensity = app.CottonMoutonNoiseSpinner.Value;


                % app.PickUp.config.noise_random_proportional_intensity = app.PickUpCoilsNoiseSpinner;

                app.PickUp = app.PickUp.measure(app.equi);
                app.SaddleCoils = app.SaddleCoils.measure(app.equi);
                app.FluxLoops = app.FluxLoops.measure(app.equi);
                app.TS = app.TS.measure(app.equi);
                app.IntPol = app.IntPol.measure(app.equi);

                %% Plot Measurements
                % Pick Up Coils
                hold(app.UI_PU, "off")
                plot(app.UI_PU,app.PickUp.ideal.B,'.b','MarkerSize',16)
                hold(app.UI_PU,"on")
                plot(app.UI_PU,app.PickUp.B,'or','MarkerSize',8,'LineWidth',1.2)
                grid(app.UI_PU,"on")
                grid(app.UI_PU,"minor")
                xlabel(app.UI_PU,"#")
                ylabel(app.UI_PU,"B [T]")
                legend(app.UI_PU,"ideal","noisy","Location","best","Orientation","horizontal")

                % Saddle Coils
                hold(app.UI_SL, "off")
                plot(app.UI_SL,app.SaddleCoils.ideal.Dpsi,'.b','MarkerSize',16)
                hold(app.UI_SL,"on")
                plot(app.UI_SL,app.SaddleCoils.Dpsi,'or','LineWidth',1.2)
                grid(app.UI_SL,"on")
                grid(app.UI_SL,"minor")
                xlabel(app.UI_SL,"#")
                ylabel(app.UI_SL,"\Delta\psi [Wb/rad]")
                legend(app.UI_PU,"ideal","noisy","Location","best","Orientation","horizontal")

                % Flux Loops
                hold(app.UI_FL, "off")
                plot(app.UI_FL,app.FluxLoops.ideal.psi,'.b','MarkerSize',16)
                hold(app.UI_FL,"on")
                plot(app.UI_FL,app.FluxLoops.psi,'or','LineWidth',1.2)
                grid(app.UI_FL,"on")
                grid(app.UI_FL,"minor")
                xlabel(app.UI_FL,"#")
                ylabel(app.UI_FL,"\psi [Wb/rad]")
                legend(app.UI_PU,"ideal","noisy","Location","best")

                % Thomson scattering - Temperature
                hold(app.UI_TS_Te, "off")
                plot(app.UI_TS_Te,app.TS.ideal.Te,'.b','MarkerSize',16)
                hold(app.UI_TS_Te,"on")
                plot(app.UI_TS_Te,app.TS.Te,'or','LineWidth',1.2)
                grid(app.UI_TS_Te,"on")
                grid(app.UI_TS_Te,"minor")
                xlabel(app.UI_TS_Te,"#")
                ylabel(app.UI_TS_Te,"T_e [eV]")
                legend(app.UI_PU,"ideal","noisy","Location","best")

                % Thomson scattering - Density
                hold(app.UI_TS_ne, "off")
                plot(app.UI_TS_ne,app.TS.ideal.ne,'.b','MarkerSize',16)
                hold(app.UI_TS_ne,"on")
                plot(app.UI_TS_ne,app.TS.ne,'or','LineWidth',1.2)
                grid(app.UI_TS_ne,"on")
                grid(app.UI_TS_ne,"minor")
                xlabel(app.UI_TS_ne,"#")
                ylabel(app.UI_TS_ne,"n_e [m^{-3}]")
                legend(app.UI_PU,"ideal","noisy","Location","best")

                % Interferometer
                hold(app.UI_Interferometer, "off")
                plot(app.UI_Interferometer,app.IntPol.LIDc,'.b','MarkerSize',16)
                hold(app.UI_Interferometer,"on")
                plot(app.UI_Interferometer,app.IntPol.LIDh,'or','MarkerSize',8,'LineWidth',1.2)
                grid(app.UI_Interferometer,"on")
                grid(app.UI_Interferometer,"minor")
                xlabel(app.UI_Interferometer,"#")
                ylabel(app.UI_Interferometer,"LID [m^{-2}]")
                legend(app.UI_Interferometer,"Cold Plasma","Hot Plasma","Location","best")

                % Faraday rotation
                hold(app.UI_Faraday, "off")
                plot(app.UI_Faraday,app.IntPol.FARc_typeI,'.k','MarkerSize',16)
                hold(app.UI_Faraday,"on")
                plot(app.UI_Faraday,app.IntPol.FARc,'sb','MarkerSize',8,'LineWidth',1.2)
                plot(app.UI_Faraday,app.IntPol.FARh,'or','MarkerSize',8,'LineWidth',1.2)
                grid(app.UI_Faraday,"on")
                grid(app.UI_Faraday,"minor")
                xlabel(app.UI_Faraday,"#")
                ylabel(app.UI_Faraday,"faraday [rad]")
                legend(app.UI_Faraday,"type-I","Cold Plasma","Hot Plasma","Location","best")

                % Cotton-Mouton
                hold(app.UI_CM, "off")
                plot(app.UI_CM,app.IntPol.CMc_typeI,'.k','MarkerSize',16)
                hold(app.UI_CM,"on")
                plot(app.UI_CM,app.IntPol.CMc,'sb','MarkerSize',8,'LineWidth',1.2)
                plot(app.UI_CM,app.IntPol.CMh,'or','MarkerSize',8,'LineWidth',1.2)
                grid(app.UI_CM,"on")
                grid(app.UI_CM,"minor")
                xlabel(app.UI_CM,"#")
                ylabel(app.UI_CM,"CM [rad]")
                legend(app.UI_CM,"type-I","Cold Plasma","Hot Plasma","Location","best")

            end


        end

        % Callback function: Button_Diagnostics, Check_FluxLoops, 
        % ...and 4 other components
        function Button_DiagnosticsPushed(app, event)

            %% Diagnostic Geometry

            hold(app.UIAxes_6, 'off')
            plot(app.UIAxes_6,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            hold(app.UIAxes_6, 'on')
            fill(app.UIAxes_6,app.geo.wall.solid_R,app.geo.wall.solid_Z,[200 200 200]/255,'LineStyle','none')

            if app.Check_PickUp.Value
                plot(app.UIAxes_6,app.PickUp.R,app.PickUp.Z,'.','MarkerSize',16,"Color","#0072BD")
            end

            if app.Check_FluxLoops.Value
                plot(app.UIAxes_6,app.FluxLoops.R,app.FluxLoops.Z,'.','MarkerSize',16,"Color","#D95319")
            end

            if app.Check_SaddleLoops.Value
                plot(app.UIAxes_6,[app.SaddleCoils.R1; app.SaddleCoils.R2],...
                    [app.SaddleCoils.Z1; app.SaddleCoils.Z2],'.-','MarkerSize',16,"Color","#7E2F8E")
            end

            if app.Check_ThomsonScattering.Value
                plot(app.UIAxes_6,app.TS.R,app.TS.Z,'.','MarkerSize',16,"Color","#77AC30")
            end

            if app.Check_InterferometerPolarimeter.Value
                plot(app.UIAxes_6, ...
                    [app.IntPol.R_in';app.IntPol.R_out'],...
                    [app.IntPol.Z_in';app.IntPol.Z_out'],...
                    '.-','MarkerSize',16,"Color","#A2142F")
            end

            axis(app.UIAxes_6,"equal")
            grid(app.UIAxes_6,"on")
            grid(app.UIAxes_6,"minor")
            xlabel(app.UIAxes_6,"R [m]")
            ylabel(app.UIAxes_6,"Z [m]")
            xlim(app.UIAxes_6,[min(app.geo.R) max(app.geo.R)])
            ylim(app.UIAxes_6,[min(app.geo.Z) max(app.geo.Z)])

        end

        % Callback function: Button_Diagnostic_Measure, 
        % ...and 10 other components
        function Button_Diagnostic_MeasurePushed(app, event)

            app.PickUp.config.noise_random_proportional_intensity = app.PickUpCoilsNoiseSpinner.Value;
            app.SaddleCoils.config.noise_random_proportional_intensity = app.SaddleLoopNoiseSpinner.Value;
            app.FluxLoops.config.noise_random_proportional_intensity = app.FluxLoopNoiseSpinner.Value;

            app.TS.config.Te_noise_random_proportional_intensity = app.TemperatureNoiseSpinner.Value;
            app.TS.config.ne_noise_random_proportional_intensity = app.DensityNoiseSpinner.Value;

            app.IntPol.config.LID_noise_random_proportional_intensity = app.InterferometerNoiseSpinner.Value;
            app.IntPol.config.FAR_noise_random_proportional_intensity = app.FaradayRotationNoiseSpinner.Value;
            app.IntPol.config.CM_noise_random_proportional_intensity = app.CottonMoutonNoiseSpinner.Value;


            % app.PickUp.config.noise_random_proportional_intensity = app.PickUpCoilsNoiseSpinner;

            app.PickUp = app.PickUp.measure(app.equi);
            app.SaddleCoils = app.SaddleCoils.measure(app.equi);
            app.FluxLoops = app.FluxLoops.measure(app.equi);
            app.TS = app.TS.measure(app.equi);
            app.IntPol = app.IntPol.measure(app.equi);

            %% Plot Measurements
            % Pick Up Coils
            hold(app.UI_PU, "off")
            plot(app.UI_PU,app.PickUp.ideal.B,'.b','MarkerSize',16)
            hold(app.UI_PU,"on")
            plot(app.UI_PU,app.PickUp.B,'or','MarkerSize',8,'LineWidth',1.2)
            grid(app.UI_PU,"on")
            grid(app.UI_PU,"minor")
            xlabel(app.UI_PU,"#")
            ylabel(app.UI_PU,"B [T]")
            legend(app.UI_PU,"ideal","noisy","Location","best","Orientation","horizontal")

            % Saddle Coils
            hold(app.UI_SL, "off")
            plot(app.UI_SL,app.SaddleCoils.ideal.Dpsi,'.b','MarkerSize',16)
            hold(app.UI_SL,"on")
            plot(app.UI_SL,app.SaddleCoils.Dpsi,'or','LineWidth',1.2)
            grid(app.UI_SL,"on")
            grid(app.UI_SL,"minor")
            xlabel(app.UI_SL,"#")
            ylabel(app.UI_SL,"\Delta\psi [Wb/rad]")
            legend(app.UI_PU,"ideal","noisy","Location","best","Orientation","horizontal")

            % Flux Loops
            hold(app.UI_FL, "off")
            plot(app.UI_FL,app.FluxLoops.ideal.psi,'.b','MarkerSize',16)
            hold(app.UI_FL,"on")
            plot(app.UI_FL,app.FluxLoops.psi,'or','LineWidth',1.2)
            grid(app.UI_FL,"on")
            grid(app.UI_FL,"minor")
            xlabel(app.UI_FL,"#")
            ylabel(app.UI_FL,"\psi [Wb/rad]")
            legend(app.UI_PU,"ideal","noisy","Location","best")

            % Thomson scattering - Temperature
            hold(app.UI_TS_Te, "off")
            plot(app.UI_TS_Te,app.TS.ideal.Te,'.b','MarkerSize',16)
            hold(app.UI_TS_Te,"on")
            plot(app.UI_TS_Te,app.TS.Te,'or','LineWidth',1.2)
            grid(app.UI_TS_Te,"on")
            grid(app.UI_TS_Te,"minor")
            xlabel(app.UI_TS_Te,"#")
            ylabel(app.UI_TS_Te,"T_e [eV]")
            legend(app.UI_PU,"ideal","noisy","Location","best")

            % Thomson scattering - Density
            hold(app.UI_TS_ne, "off")
            plot(app.UI_TS_ne,app.TS.ideal.ne,'.b','MarkerSize',16)
            hold(app.UI_TS_ne,"on")
            plot(app.UI_TS_ne,app.TS.ne,'or','LineWidth',1.2)
            grid(app.UI_TS_ne,"on")
            grid(app.UI_TS_ne,"minor")
            xlabel(app.UI_TS_ne,"#")
            ylabel(app.UI_TS_ne,"n_e [m^{-3}]")
            legend(app.UI_PU,"ideal","noisy","Location","best")

            % Interferometer
            hold(app.UI_Interferometer, "off")
            plot(app.UI_Interferometer,app.IntPol.LIDc,'.b','MarkerSize',16)
            hold(app.UI_Interferometer,"on")
            plot(app.UI_Interferometer,app.IntPol.LIDh,'or','MarkerSize',8,'LineWidth',1.2)
            grid(app.UI_Interferometer,"on")
            grid(app.UI_Interferometer,"minor")
            xlabel(app.UI_Interferometer,"#")
            ylabel(app.UI_Interferometer,"LID [m^{-2}]")
            legend(app.UI_Interferometer,"Cold Plasma","Hot Plasma","Location","best")

            % Faraday rotation
            hold(app.UI_Faraday, "off")
            plot(app.UI_Faraday,app.IntPol.FARc_typeI,'.k','MarkerSize',16)
            hold(app.UI_Faraday,"on")
            plot(app.UI_Faraday,app.IntPol.FARc,'sb','MarkerSize',8,'LineWidth',1.2)
            plot(app.UI_Faraday,app.IntPol.FARh,'or','MarkerSize',8,'LineWidth',1.2)
            grid(app.UI_Faraday,"on")
            grid(app.UI_Faraday,"minor")
            xlabel(app.UI_Faraday,"#")
            ylabel(app.UI_Faraday,"faraday [rad]")
            legend(app.UI_Faraday,"type-I","Cold Plasma","Hot Plasma","Location","best")

            % Cotton-Mouton
            hold(app.UI_CM, "off")
            plot(app.UI_CM,app.IntPol.CMc_typeI,'.k','MarkerSize',16)
            hold(app.UI_CM,"on")
            plot(app.UI_CM,app.IntPol.CMc,'sb','MarkerSize',8,'LineWidth',1.2)
            plot(app.UI_CM,app.IntPol.CMh,'or','MarkerSize',8,'LineWidth',1.2)
            grid(app.UI_CM,"on")
            grid(app.UI_CM,"minor")
            xlabel(app.UI_CM,"#")
            ylabel(app.UI_CM,"CM [rad]")
            legend(app.UI_CM,"type-I","Cold Plasma","Hot Plasma","Location","best")

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {658, 658, 658};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {658, 658};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 571};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Hyperlink_GitHub = uihyperlink(app.GridLayout2);
            app.Hyperlink_GitHub.URL = 'https://github.com/TokaLab/VirtualLab';
            app.Hyperlink_GitHub.Text = 'GitHub';
            app.Hyperlink_Mathwork_FileExchange = uihyperlink(app.GridLayout2);
            app.Hyperlink_Mathwork_FileExchange.URL = 'https://it.mathworks.com/matlabcentral/fileexchange/182014-tokalab';
            app.Hyperlink_Mathwork_FileExchange.Text = 'FileExhange';
            app.Hyperlink_LinkedIn = uihyperlink(app.GridLayout2);
            app.Hyperlink_LinkedIn.URL = 'https://www.linkedin.com/company/tokalab-fusion/';
            app.Hyperlink_LinkedIn.Text = 'LinkedIn';
            app.UpperTriangularityLabel = uilabel(app.GridLayout2);
            app.UpperTriangularityLabel.Text = 'Upper Triangularity';
            app.BottomTriangularityLabel = uilabel(app.GridLayout2);
            app.BottomTriangularityLabel.Text = 'Bottom Triangularity';
            app.UpperElongationLabel = uilabel(app.GridLayout2);
            app.UpperElongationLabel.Text = 'Upper Elongation';
            app.BottomElongationLabel = uilabel(app.GridLayout2);
            app.BottomElongationLabel.Text = 'Bottom Elongation';
            app.gammaupleftLabel = uilabel(app.GridLayout2);
            app.gammaupleftLabel.Text = 'gamma up left';
            app.gammauprightLabel = uilabel(app.GridLayout2);
            app.gammauprightLabel.Text = 'gamma up right';
            app.gammabottomleftLabel = uilabel(app.GridLayout2);
            app.gammabottomleftLabel.Text = 'gamma bottom left';
            app.gammabottomrightLabel = uilabel(app.GridLayout2);
            app.gammabottomrightLabel.Text = 'gamma bottom right';
            app.k1SpinnerLabel = uilabel(app.GridLayout2);
            app.k1SpinnerLabel.Visible = 'off';
            app.k1SpinnerLabel.Text = 'k1';
            app.k1Spinner = uispinner(app.GridLayout2);
            app.k1Spinner.Step = 0.1;
            app.k1Spinner.Limits = [0.5 3];
            app.k1Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.k1Spinner.Value = 1.7;
            app.k2SpinnerLabel = uilabel(app.GridLayout2);
            app.k2SpinnerLabel.Visible = 'off';
            app.k2SpinnerLabel.Text = 'k2';
            app.k2Spinner = uispinner(app.GridLayout2);
            app.k2Spinner.Step = 0.1;
            app.k2Spinner.Limits = [0.5 3];
            app.k2Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.k2Spinner.Value = 2;
            app.d1SpinnerLabel = uilabel(app.GridLayout2);
            app.d1SpinnerLabel.Visible = 'off';
            app.d1SpinnerLabel.Text = 'd1';
            app.d1Spinner = uispinner(app.GridLayout2);
            app.d1Spinner.Step = 0.1;
            app.d1Spinner.Limits = [0 5];
            app.d1Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.d1Spinner.Value = 0.5;
            app.d2SpinnerLabel = uilabel(app.GridLayout2);
            app.d2SpinnerLabel.Visible = 'off';
            app.d2SpinnerLabel.Text = 'd2';
            app.d2Spinner = uispinner(app.GridLayout2);
            app.d2Spinner.Step = 0.1;
            app.d2Spinner.Limits = [0 5];
            app.d2Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.d2Spinner.Value = 0.5;
            app.gn1SpinnerLabel = uilabel(app.GridLayout2);
            app.gn1SpinnerLabel.Visible = 'off';
            app.gn1SpinnerLabel.Text = 'gn1';
            app.gn1Spinner = uispinner(app.GridLayout2);
            app.gn1Spinner.Limits = [0 90];
            app.gn1Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.gn2SpinnerLabel = uilabel(app.GridLayout2);
            app.gn2SpinnerLabel.Visible = 'off';
            app.gn2SpinnerLabel.Text = 'gn2';
            app.gn2Spinner = uispinner(app.GridLayout2);
            app.gn2Spinner.Step = 0.1;
            app.gn2Spinner.Limits = [0 90];
            app.gn2Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.gn2Spinner.Value = 60;
            app.gp1SpinnerLabel = uilabel(app.GridLayout2);
            app.gp1SpinnerLabel.Visible = 'off';
            app.gp1SpinnerLabel.Text = 'gp1';
            app.gp1Spinner = uispinner(app.GridLayout2);
            app.gp1Spinner.Step = 0.1;
            app.gp1Spinner.Limits = [0 90];
            app.gp1Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.gp2SpinnerLabel = uilabel(app.GridLayout2);
            app.gp2SpinnerLabel.Visible = 'off';
            app.gp2SpinnerLabel.Text = 'gp2';
            app.gp2Spinner = uispinner(app.GridLayout2);
            app.gp2Spinner.Step = 0.1;
            app.gp2Spinner.Limits = [0 90];
            app.gp2Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.gp2Spinner.Value = 30;
            app.TargetSeparatrixShapeLabel = uilabel(app.GridLayout2);
            app.TargetSeparatrixShapeLabel.Text = 'Target Separatrix Shape';
            app.Button_Initialise = uibutton(app.GridLayout2, 'push');
            app.Button_Initialise.ButtonPushedFcn = createCallbackFcn(app, @Button_InitialisePushed, true);
            app.Button_Initialise.Text = 'Initialise';
            app.ProfileHyperparametersLabel = uilabel(app.GridLayout2);
            app.ProfileHyperparametersLabel.Text = 'Profile Hyper-parameters';
            app.Button_Update = uibutton(app.GridLayout2, 'push');
            app.Button_Update.ButtonPushedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.Button_Update.Text = 'Update';
            app.RmagSpinnerLabel = uilabel(app.GridLayout2);
            app.RmagSpinnerLabel.Text = 'R mag';
            app.RmagSpinner = uispinner(app.GridLayout2);
            app.RmagSpinner.Step = 0.1;
            app.RmagSpinner.Limits = [0 Inf];
            app.RmagSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.RmagSpinner.Value = 6;
            app.ZmagSpinnerLabel = uilabel(app.GridLayout2);
            app.ZmagSpinnerLabel.Text = 'Z mag';
            app.ZmagSpinner = uispinner(app.GridLayout2);
            app.ZmagSpinner.Step = 0.1;
            app.ZmagSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.amagSpinnerLabel = uilabel(app.GridLayout2);
            app.amagSpinnerLabel.Text = 'a mag';
            app.amagSpinner = uispinner(app.GridLayout2);
            app.amagSpinner.Step = 0.1;
            app.amagSpinner.Limits = [0 Inf];
            app.amagSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.amagSpinner.Value = 2;
            app.Check_GSsolver = uicheckbox(app.GridLayout2);
            app.Check_GSsolver.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.Check_GSsolver.Text = 'GS solver';
            app.Check_GSsolver.Value = true;
            app.IterationSpinnerLabel = uilabel(app.GridLayout2);
            app.IterationSpinnerLabel.Text = 'Iteration';
            app.IterationSpinner = uispinner(app.GridLayout2);
            app.IterationSpinner.Limits = [1 100];
            app.IterationSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.IterationSpinner.Value = 1;
            app.B_tTSpinnerLabel = uilabel(app.GridLayout2);
            app.B_tTSpinnerLabel.Text = 'B_t [T]';
            app.B_tTSpinner = uispinner(app.GridLayout2);
            app.B_tTSpinner.Step = 0.1;
            app.B_tTSpinner.Limits = [0 Inf];
            app.B_tTSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.B_tTSpinner.Value = 6;
            app.I_pMASpinnerLabel = uilabel(app.GridLayout2);
            app.I_pMASpinnerLabel.Text = 'I_p [MA]';
            app.I_pMASpinner = uispinner(app.GridLayout2);
            app.I_pMASpinner.Step = 0.1;
            app.I_pMASpinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.I_pMASpinner.Value = -12;
            app.J_ta_1SpinnerLabel = uilabel(app.GridLayout2);
            app.J_ta_1SpinnerLabel.Text = 'J_t a_1';
            app.J_ta_1Spinner = uispinner(app.GridLayout2);
            app.J_ta_1Spinner.Step = 0.1;
            app.J_ta_1Spinner.Limits = [0 Inf];
            app.J_ta_1Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.J_ta_1Spinner.Value = 2;
            app.J_ta_2SpinnerLabel = uilabel(app.GridLayout2);
            app.J_ta_2SpinnerLabel.Text = 'J_t a_2';
            app.J_ta_2Spinner = uispinner(app.GridLayout2);
            app.J_ta_2Spinner.Step = 0.1;
            app.J_ta_2Spinner.Limits = [0 Inf];
            app.J_ta_2Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.J_ta_2Spinner.Value = 2;
            app.beta_0SpinnerLabel = uilabel(app.GridLayout2);
            app.beta_0SpinnerLabel.Text = 'beta_0';
            app.beta_0Spinner = uispinner(app.GridLayout2);
            app.beta_0Spinner.Step = 0.1;
            app.beta_0Spinner.Limits = [0 Inf];
            app.beta_0Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.beta_0Spinner.Value = 0.5;
            app.n_01e19SpinnerLabel = uilabel(app.GridLayout2);
            app.n_01e19SpinnerLabel.Text = 'n_0 [1e19]';
            app.n_01e19Spinner = uispinner(app.GridLayout2);
            app.n_01e19Spinner.Step = 0.1;
            app.n_01e19Spinner.Limits = [0 Inf];
            app.n_01e19Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.n_01e19Spinner.Value = 10;
            app.na_1SpinnerLabel = uilabel(app.GridLayout2);
            app.na_1SpinnerLabel.Text = 'n a_1';
            app.na_1Spinner = uispinner(app.GridLayout2);
            app.na_1Spinner.Step = 0.1;
            app.na_1Spinner.Limits = [0 Inf];
            app.na_1Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.na_1Spinner.Value = 2;
            app.na_2SpinnerLabel = uilabel(app.GridLayout2);
            app.na_2SpinnerLabel.Text = 'n a_2';
            app.na_2Spinner = uispinner(app.GridLayout2);
            app.na_2Spinner.Step = 0.1;
            app.na_2Spinner.Limits = [0 Inf];
            app.na_2Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.na_2Spinner.Value = 3;
            app.n_s1e19SpinnerLabel = uilabel(app.GridLayout2);
            app.n_s1e19SpinnerLabel.Text = 'n_s [1e19]';
            app.n_s1e19Spinner = uispinner(app.GridLayout2);
            app.n_s1e19Spinner.Step = 0.001;
            app.n_s1e19Spinner.Limits = [0 Inf];
            app.n_s1e19Spinner.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.n_s1e19Spinner.Value = 0.01;
            app.Check_ShowProfile = uicheckbox(app.GridLayout2);
            app.Check_ShowProfile.Text = 'Show Profile';
            app.Check_ShowProfile.Value = true;
            app.Image_TokaLab_banner = uiimage(app.GridLayout2);
            app.Image_TokaLab_banner.ImageSource = fullfile(pathToMLAPP, 'images', 'TokaLab_banner.png');
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes_5 = uiaxes(app.GridLayout3);
            title(app.UIAxes_5, 'GS - solution')
            xlabel(app.UIAxes_5, 'R [m]')
            ylabel(app.UIAxes_5, 'Z [m]')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout3);
            title(app.UIAxes_4, 'Profile')
            xlabel(app.UIAxes_4, '\psi_n [arb.units]')
            ylabel(app.UIAxes_4, 'T_e(\psi_n) [eV]')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout3);
            title(app.UIAxes_3, 'Profile ')
            xlabel(app.UIAxes_3, '\psi_n [arb.units]')
            ylabel(app.UIAxes_3, 'n_e(\psi_n) [m^{-3}]')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout3);
            title(app.UIAxes_2, 'GS - solution')
            xlabel(app.UIAxes_2, 'R [m]')
            ylabel(app.UIAxes_2, 'Z [m]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_1 = uiaxes(app.GridLayout3);
            title(app.UIAxes_1, 'Target Separatrix')
            xlabel(app.UIAxes_1, 'R [m]')
            ylabel(app.UIAxes_1, 'Z [m]')
            zlabel(app.UIAxes_1, 'Z')
            app.SelectFieldDropDownLabel = uilabel(app.GridLayout3);
            app.SelectFieldDropDownLabel.Text = 'Select Field';
            app.Select_1 = uidropdown(app.GridLayout3);
            app.Select_1.Items = {'psi', 'Br', 'Bt', 'Bz', 'Jr', 'Jt', 'Jz', 'pe', 'ne', 'Te', 'pi', 'ni', 'Ti'};
            app.Select_1.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.Select_1.Value = 'Jt';
            app.SelectFieldDropDown_2Label = uilabel(app.GridLayout3);
            app.SelectFieldDropDown_2Label.Text = 'Select Field';
            app.Select_2 = uidropdown(app.GridLayout3);
            app.Select_2.Items = {'psi', 'Br', 'Bt', 'Bz', 'Jr', 'Jt', 'Jz', 'pe', 'ne', 'Te', 'pi', 'ni', 'Ti'};
            app.Select_2.ValueChangedFcn = createCallbackFcn(app, @Button_UpdatePushed, true);
            app.Select_2.Value = 'ne';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.UIAxes_6 = uiaxes(app.GridLayout4);
            title(app.UIAxes_6, 'Diagnostics')
            xlabel(app.UIAxes_6, 'R [m]')
            ylabel(app.UIAxes_6, 'Z [m]')
            zlabel(app.UIAxes_6, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.MagneticsTab = uitab(app.TabGroup);
            app.MagneticsTab.Title = 'Magnetics';
            app.GridLayout5 = uigridlayout(app.MagneticsTab);
            app.UI_PU = uiaxes(app.GridLayout5);
            title(app.UI_PU, 'Pick Up Coils')
            xlabel(app.UI_PU, 'X')
            ylabel(app.UI_PU, 'B [T]')
            zlabel(app.UI_PU, 'Z')
            app.UI_SL = uiaxes(app.GridLayout5);
            title(app.UI_SL, 'Saddle Coils')
            xlabel(app.UI_SL, 'X')
            ylabel(app.UI_SL, '\Delta\psi [Wb/rad]')
            zlabel(app.UI_SL, 'Z')
            app.UI_FL = uiaxes(app.GridLayout5);
            title(app.UI_FL, 'Flux Loops')
            xlabel(app.UI_FL, 'X')
            ylabel(app.UI_FL, '\psi [Wb/rad]')
            zlabel(app.UI_FL, 'Z')
            app.PickUpNoiseSpinner = uispinner(app.GridLayout5);
            app.PickUpNoiseSpinner.Step = 0.1;
            app.PickUpNoiseSpinner.Limits = [0 5];
            app.PickUpNoiseSpinner.Value = 0.5;
            app.SaddleLoopNoiseLabel = uilabel(app.GridLayout5);
            app.SaddleLoopNoiseLabel.Text = 'Saddle Loop - Noise [%]';
            app.SaddleLoopNoiseSpinner = uispinner(app.GridLayout5);
            app.SaddleLoopNoiseSpinner.Step = 0.01;
            app.SaddleLoopNoiseSpinner.Limits = [0 5];
            app.SaddleLoopNoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.FluxLoopNoiseLabel = uilabel(app.GridLayout5);
            app.FluxLoopNoiseLabel.Text = 'Flux Loop - Noise [%]';
            app.FluxLoopNoiseSpinner = uispinner(app.GridLayout5);
            app.FluxLoopNoiseSpinner.Step = 0.01;
            app.FluxLoopNoiseSpinner.Limits = [0 5];
            app.FluxLoopNoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.PickUpCoilsNoiseLabel = uilabel(app.GridLayout5);
            app.PickUpCoilsNoiseLabel.Text = 'Pick Up Coils - Noise [%]';
            app.PickUpCoilsNoiseSpinner = uispinner(app.GridLayout5);
            app.PickUpCoilsNoiseSpinner.Step = 0.01;
            app.PickUpCoilsNoiseSpinner.Limits = [0 5];
            app.PickUpCoilsNoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.ThomsonScatteringTab = uitab(app.TabGroup);
            app.ThomsonScatteringTab.SizeChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.ThomsonScatteringTab.Title = 'Thomson Scattering';
            app.GridLayout6 = uigridlayout(app.ThomsonScatteringTab);
            app.UI_TS_Te = uiaxes(app.GridLayout6);
            title(app.UI_TS_Te, 'Eleectron Temperature')
            xlabel(app.UI_TS_Te, 'X')
            ylabel(app.UI_TS_Te, 'Y')
            zlabel(app.UI_TS_Te, 'Z')
            app.UI_TS_ne = uiaxes(app.GridLayout6);
            title(app.UI_TS_ne, 'Electron Density')
            xlabel(app.UI_TS_ne, 'X')
            ylabel(app.UI_TS_ne, 'Y')
            zlabel(app.UI_TS_ne, 'Z')
            app.TemperatureNoiseLabel = uilabel(app.GridLayout6);
            app.TemperatureNoiseLabel.Text = 'Temperature - Noise [%]';
            app.TemperatureNoiseSpinner = uispinner(app.GridLayout6);
            app.TemperatureNoiseSpinner.Step = 0.01;
            app.TemperatureNoiseSpinner.Limits = [0 5];
            app.TemperatureNoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.DensityNoiseLabel = uilabel(app.GridLayout6);
            app.DensityNoiseLabel.Text = 'Density - Noise [%]';
            app.DensityNoiseSpinner = uispinner(app.GridLayout6);
            app.DensityNoiseSpinner.Step = 0.01;
            app.DensityNoiseSpinner.Limits = [0 5];
            app.DensityNoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.InterferometerPolarimeterTab = uitab(app.TabGroup);
            app.InterferometerPolarimeterTab.SizeChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.InterferometerPolarimeterTab.Title = 'Interferometer-Polarimeter';
            app.GridLayout7 = uigridlayout(app.InterferometerPolarimeterTab);
            app.UI_Interferometer = uiaxes(app.GridLayout7);
            title(app.UI_Interferometer, 'Title')
            xlabel(app.UI_Interferometer, 'X')
            ylabel(app.UI_Interferometer, 'Y')
            zlabel(app.UI_Interferometer, 'Z')
            app.UI_Faraday = uiaxes(app.GridLayout7);
            title(app.UI_Faraday, 'Title')
            xlabel(app.UI_Faraday, 'X')
            ylabel(app.UI_Faraday, 'Y')
            zlabel(app.UI_Faraday, 'Z')
            app.UI_CM = uiaxes(app.GridLayout7);
            title(app.UI_CM, 'CM')
            xlabel(app.UI_CM, 'X')
            ylabel(app.UI_CM, 'Y')
            zlabel(app.UI_CM, 'Z')
            app.InterferometerNoiseLabel = uilabel(app.GridLayout7);
            app.InterferometerNoiseLabel.Text = 'Interferometer - Noise [%]';
            app.InterferometerNoiseSpinner = uispinner(app.GridLayout7);
            app.InterferometerNoiseSpinner.Step = 0.01;
            app.InterferometerNoiseSpinner.Limits = [0 5];
            app.InterferometerNoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.FaradayRotationNoiseLabel = uilabel(app.GridLayout7);
            app.FaradayRotationNoiseLabel.Text = 'Faraday Rotation - Noise [%]';
            app.FaradayRotationNoiseSpinner = uispinner(app.GridLayout7);
            app.FaradayRotationNoiseSpinner.Step = 0.01;
            app.FaradayRotationNoiseSpinner.Limits = [0 5];
            app.FaradayRotationNoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.CottonMoutonNoiseLabel = uilabel(app.GridLayout7);
            app.CottonMoutonNoiseLabel.Text = 'Cotton-Mouton - Noise [%]';
            app.CottonMoutonNoiseSpinner = uispinner(app.GridLayout7);
            app.CottonMoutonNoiseSpinner.Step = 0.01;
            app.CottonMoutonNoiseSpinner.Limits = [0 5];
            app.CottonMoutonNoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.DiagnosticsModuleSynDiagLabel = uilabel(app.GridLayout4);
            app.DiagnosticsModuleSynDiagLabel.Text = 'Diagnostics - Module SynDiag';
            app.Check_UpdateMeasurement = uicheckbox(app.GridLayout4);
            app.Check_UpdateMeasurement.Text = 'Update Measurements';
            app.Check_UpdateMeasurement.Value = true;
            app.Button_Diagnostics = uibutton(app.GridLayout4, 'push');
            app.Button_Diagnostics.ButtonPushedFcn = createCallbackFcn(app, @Button_DiagnosticsPushed, true);
            app.Button_Diagnostics.Text = 'Update - Geometry';
            app.Check_SaddleLoops = uicheckbox(app.GridLayout4);
            app.Check_SaddleLoops.ValueChangedFcn = createCallbackFcn(app, @Button_DiagnosticsPushed, true);
            app.Check_SaddleLoops.Text = 'Saddle Loops';
            app.Check_SaddleLoops.Value = true;
            app.Check_PickUp = uicheckbox(app.GridLayout4);
            app.Check_PickUp.ValueChangedFcn = createCallbackFcn(app, @Button_DiagnosticsPushed, true);
            app.Check_PickUp.Text = 'Pick Up Coils';
            app.Check_PickUp.Value = true;
            app.Check_FluxLoops = uicheckbox(app.GridLayout4);
            app.Check_FluxLoops.ValueChangedFcn = createCallbackFcn(app, @Button_DiagnosticsPushed, true);
            app.Check_FluxLoops.Text = 'Flux Loops';
            app.Check_FluxLoops.Value = true;
            app.Check_ThomsonScattering = uicheckbox(app.GridLayout4);
            app.Check_ThomsonScattering.ValueChangedFcn = createCallbackFcn(app, @Button_DiagnosticsPushed, true);
            app.Check_ThomsonScattering.Text = 'Thomson Scattering';
            app.Check_ThomsonScattering.Value = true;
            app.Check_InterferometerPolarimeter = uicheckbox(app.GridLayout4);
            app.Check_InterferometerPolarimeter.ValueChangedFcn = createCallbackFcn(app, @Button_DiagnosticsPushed, true);
            app.Check_InterferometerPolarimeter.Text = 'Interferometer-Polarimeter';
            app.Check_InterferometerPolarimeter.Value = true;
            app.Button_Diagnostic_Measure = uibutton(app.GridLayout4, 'push');
            app.Button_Diagnostic_Measure.ButtonPushedFcn = createCallbackFcn(app, @Button_Diagnostic_MeasurePushed, true);
            app.Button_Diagnostic_Measure.Text = 'Update - Measurments';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TokaLab_app_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end